classdef configurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DaqServerDropDownLabel        matlab.ui.control.Label
        DaqServerDropDown             matlab.ui.control.DropDown
        CenterPanel                   matlab.ui.container.Panel
        SaveLoadPanel                 matlab.ui.container.Panel
        LoadSettingsButton            matlab.ui.control.Button
        SaveSettingsButton            matlab.ui.control.Button
        ExperimentScriptsPanel        matlab.ui.container.Panel
        TaskFileButton                matlab.ui.control.Button
        InitializationFileButton      matlab.ui.control.Button
        ConditionSelectionFileButton  matlab.ui.control.Button
        BlockSelectionFileButton      matlab.ui.control.Button
        TaskLabel                     matlab.ui.control.Label
        InitializationLabel           matlab.ui.control.Label
        ConditionSelectionLabel       matlab.ui.control.Label
        BlockSelectionLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        cfg = ArcadeConfig % Description
    end
    
    methods ( Static, Access = public)
        
        function FileSelectButton(app, event)
            [filename, pathname, ~] = uigetfile('*.m', 'Pick a MATLAB code file');
            if filename == 0
                event.Source.Text = 'None2';
            else
                event.Source.Text = filename;
                event.Source.Tooltip = fullfile(pathname, filename);
                app.cfg.taskFile = fullfile(pathname, filename);
            end
        end
        
        function binaries = get_binaries_in_folder(folder)
            files = dir(folder);
            files = files(~[files.isdir]);
            
            binaries = {};
            for iFile = 1:length(files)
                file = files(iFile);
                [~, ~, ext] = fileparts(file.name);
                if ismember(ext, {'.cmd', '.bat', '.exe'})
                    binaries{end+1} = file.name;
                end
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DaqServerDropDown.Items = app.get_binaries_in_folder(fullfile(arcaderoot, 'arcade', 'DaqServer'));
            app.DaqServerDropDown.Value = app.DaqServerDropDown.Items{1};
        end

        % Button pushed function: TaskFileButton
        function TaskFileButtonPushed(app, event)
            app.FileSelectButton(app, event)
        end

        % Value changed function: DaqServerDropDown
        function DaqServerDropDownValueChanged(app, event)
            value = app.DaqServerDropDown.Value;
            app.cfg.DaqServer = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {671, 671, 671};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {671, 671};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x', 227};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DaqServerDropDownLabel = uilabel(app.LeftPanel);
            app.DaqServerDropDownLabel.Text = 'DaqServer';
            app.DaqServerDropDown = uidropdown(app.LeftPanel);
            app.DaqServerDropDown.Items = {'None'};
            app.DaqServerDropDown.ValueChangedFcn = createCallbackFcn(app, @DaqServerDropDownValueChanged, true);
            app.DaqServerDropDown.Value = 'None';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SaveLoadPanel = uipanel(app.CenterPanel);
            app.SaveLoadPanel.Title = 'Save/Load';
            app.LoadSettingsButton = uibutton(app.SaveLoadPanel, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.SaveLoadPanel, 'push');
            app.SaveSettingsButton.Text = 'Save Settings';
            app.ExperimentScriptsPanel = uipanel(app.CenterPanel);
            app.ExperimentScriptsPanel.Title = 'Experiment Scripts';
            app.TaskFileButton = uibutton(app.ExperimentScriptsPanel, 'push');
            app.TaskFileButton.ButtonPushedFcn = createCallbackFcn(app, @TaskFileButtonPushed, true);
            app.TaskFileButton.Tooltip = {'Click to select'};
            app.TaskFileButton.Text = 'None';
            app.InitializationFileButton = uibutton(app.ExperimentScriptsPanel, 'push');
            app.InitializationFileButton.Tooltip = {'Click to select'};
            app.InitializationFileButton.Text = 'None';
            app.ConditionSelectionFileButton = uibutton(app.ExperimentScriptsPanel, 'push');
            app.ConditionSelectionFileButton.Tooltip = {'Click to select'};
            app.ConditionSelectionFileButton.Text = 'None';
            app.BlockSelectionFileButton = uibutton(app.ExperimentScriptsPanel, 'push');
            app.BlockSelectionFileButton.Tooltip = {'Click to select'};
            app.BlockSelectionFileButton.Text = 'None';
            app.TaskLabel = uilabel(app.ExperimentScriptsPanel);
            app.TaskLabel.Text = 'Task';
            app.InitializationLabel = uilabel(app.ExperimentScriptsPanel);
            app.InitializationLabel.Text = 'Initialization';
            app.ConditionSelectionLabel = uilabel(app.ExperimentScriptsPanel);
            app.ConditionSelectionLabel.Text = 'Condition Selection';
            app.BlockSelectionLabel = uilabel(app.ExperimentScriptsPanel);
            app.BlockSelectionLabel.Text = 'Block Selection';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configurator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end